classdef EdgeAnalysisTool < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        EdgeDetectionAnalysisAppUIFigure  matlab.ui.Figure
        GridLayout              matlab.ui.container.GridLayout
        LeftPanel               matlab.ui.container.Panel
        OpenImageButton         matlab.ui.control.Button
        Panel                   matlab.ui.container.Panel
        DropDown                matlab.ui.control.DropDown
        LabelDropDown           matlab.ui.control.Label
        ApplyEdgeDetection      matlab.ui.control.Button
        FindSimilarButton       matlab.ui.control.Button
        RightPanel              matlab.ui.container.Panel
        EdgeDetectionToolLabel  matlab.ui.control.Label
        EdgeAxes                matlab.ui.control.UIAxes
        ImageAxes               matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = public)
        %Property % Description
        img                 % Original Image
        detectedEdges       % Detected Edges
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: OpenImageButton
        function OpenImageButtonPushed(app, event)
            [filename, pathname] = uigetfile({'*.jpg;*.jpeg;*.png'}, 'Select Image');
            if ~isequal(filename, 0)
                app.img = imread(fullfile(pathname, filename));
                
                imshow(app.img, 'Parent', app.ImageAxes);
                app.img = rgb2gray(app.img); % Convert to grayscale
                app.detectedEdges = [];
                cla(app.EdgeAxes);
            end
        end

        % Button pushed function: ApplyEdgeDetection
        function ApplyEdgeDetectionButtonPushed(app, event)
            if isempty(app.img)
                return;
            end
            selectedMethod = app.DropDown.Value;
            img= app.img
            switch selectedMethod
                case 'Sobel'
                    edges = edge(img, 'sobel', 'nothinning');
                case 'Prewitt'
                    edges = edge(img, 'prewitt');
                case 'Canny'
                    edges = edge(img, 'canny');
                case 'Adaptive Canny'
                    meanVal = mean2(img);
                    lower = max(0, round(0.66 * meanVal)/255);
                    upper = min(255, round(1.33 * meanVal)/255);
                    edges = edge(img, 'canny', [lower, upper]);
                case 'Multi-Scale'
                    scales = [0.5, 1.0, 1.5];
                    weights = [0.2, 0.5, 0.3];  % Assign weights to each scale
                    
                    edgesSum = zeros(size(img), 'double');
                    for i = 1:length(scales)
                        scale = scales(i);
                        weight = weights(i);
                        
                        resizedImg = imresize(img, scale);
                        % Apply Gaussian smoothing before edge detection
                        smoothedImg = imgaussfilt(resizedImg, 1.5); % Adjust sigma as needed
                        edges = edge(smoothedImg, 'canny');
                        resizedEdges = imresize(edges, size(img));
                        
                        edgesSum = edgesSum + weight * double(resizedEdges);
                    end
                    % Apply thresholding to extract final edges - 0.5
                    edges = edgesSum > 0.5;
                case 'Hybrid'
                    sobelEdges = edge(img, 'sobel');
                    cannyEdges = edge(img, 'canny');
                    combinedEdges = (double(sobelEdges) + double(cannyEdges)) / 2;
                    edges = uint8(combinedEdges * 255);
                otherwise
                    edges = img;
            end
           
            app.detectedEdges = edges;
            imshow(app.detectedEdges, 'Parent', app.EdgeAxes);
        
        end

        % Button pushed function: FindSimilarButton
        function FindSimilarButtonPushed(app, event)
            if isempty(app.img) || isempty(app.detectedEdges)
                return;
            end
            folderPath = uigetdir('Select Folder of Images');
            if folderPath == 0
                return;
            end
            
            fileList = dir(fullfile(folderPath, '*.png'));
            similarityScores = cell(length(fileList), 2);  % Pre-allocate memory
            selectedMethod = app.DropDown.Value;

            for k = 1:length(fileList)
                filePath = fullfile(folderPath, fileList(k).name);
                datasetImg = imread(filePath);
            
                % Convert to grayscale
                if size(datasetImg, 3) == 3
                    img = rgb2gray(datasetImg);
                else
                    img = datasetImg;
                end
            
                % Detect edges in dataset image using the selected method
                switch selectedMethod
                    case 'Sobel'
                        edges = edge(img, 'sobel', 'nothinning');
                    case 'Prewitt'
                        edges = edge(img, 'prewitt');
                    case 'Canny'
                        edges = edge(img, 'canny');
                    case 'Adaptive Canny'
                        meanVal = mean2(img);
                        lower = max(0, round(0.66 * meanVal) / 255);
                        upper = min(1, round(1.33 * meanVal) / 255);
                        edges = edge(img, 'canny', [lower, upper]);
                    case 'Multi-Scale'
                        scales = [0.5, 1.0, 1.5];
                        weights = [0.2, 0.5, 0.3];  % Assign weights to each scale
                        edgesSum = zeros(size(img), 'double');
                        for i = 1:length(scales)
                            scale = scales(i);
                            weight = weights(i);
                            resizedImg = imresize(img, scale);
                            smoothedImg = imgaussfilt(resizedImg, 1.5); % Adjust sigma as needed
                            scaleEdges = edge(smoothedImg, 'canny');
                            resizedEdges = imresize(scaleEdges, size(img));
                            edgesSum = edgesSum + weight * double(resizedEdges);
                        end
                        edges = edgesSum > 0.5;
                    case 'Hybrid'
                        sobelEdges = edge(img, 'sobel');
                        cannyEdges = edge(img, 'canny');
                        combinedEdges = (double(sobelEdges) + double(cannyEdges)) / 2;
                        edges = uint8(combinedEdges * 255);
                    otherwise
                        edges = img;
                end
            
                % Resize dataset edges to match size of app.detectedEdges
                resizedEdges = imresize(edges, size(app.detectedEdges));
            
                % Compute SSIM between the resized edges
                similarityValue = ssim(double(app.detectedEdges), double(resizedEdges));  % Compute SSIM
            
                % Store the file path and similarity score
                similarityScores{k, 1} = filePath;    % File path
                similarityScores{k, 2} = similarityValue;  % SSIM score
            end

            
            % Ensure there are valid similarity scores
            if isempty(similarityScores)
                disp('No similarity scores computed. Ensure valid inputs.');
                return;
            end
            
            % Sort by similarity score (column 2)
            try
                similarityScores = sortrows(similarityScores, -2);  % Sort descending by score
            catch ME
                disp('Error during sorting. Check similarityScores structure:');
                disp(ME.message);
                return;
            end
            
            % Display top N similar images
            numSimilarImages = min(5, size(similarityScores, 1));  % Limit to 5 images
            topSimilars = similarityScores(1:numSimilarImages, 1);
            
            % Display top similar images in a figure
            figure;
            for i = 1:length(topSimilars)
                subplot(1, length(topSimilars), i);
                img = imread(topSimilars{i});
                imshow(img);
                title(sprintf('Score: %.2f', similarityScores{i, 2}));
            end
        
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.EdgeDetectionAnalysisAppUIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {501, 501};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {220, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.EdgeDetectionAnalysisAppUIFigure = uifigure('Visible', 'off');
            app.EdgeDetectionAnalysisAppUIFigure.Name = 'Edge Detection Analysis App';
            app.EdgeDetectionAnalysisAppUIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.EdgeDetectionAnalysisAppUIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.Panel = uipanel(app.LeftPanel);
            app.FindSimilarButton = uibutton(app.Panel, 'push');
            app.FindSimilarButton.ButtonPushedFcn = createCallbackFcn(app, @FindSimilarButtonPushed, true);
            app.FindSimilarButton.Text = 'Find Similar';
            app.ApplyEdgeDetection = uibutton(app.Panel, 'push');
            app.ApplyEdgeDetection.ButtonPushedFcn = createCallbackFcn(app, @ApplyEdgeDetectionButtonPushed, true);
            app.ApplyEdgeDetection.Text = 'ApplyEdgeDetection';
            app.LabelDropDown = uilabel(app.Panel);
            app.LabelDropDown.Text = 'EdgeDetectionDropdown';
            app.DropDown = uidropdown(app.Panel);
            app.DropDown.Items = {'Sobel', 'Prewitt', 'Canny', 'Adaptive Canny', 'Multi-Scale', 'Hybrid'};
            app.DropDown.Value = 'Sobel';
            app.OpenImageButton = uibutton(app.LeftPanel, 'push');
            app.OpenImageButton.ButtonPushedFcn = createCallbackFcn(app, @OpenImageButtonPushed, true);
            app.OpenImageButton.Text = 'OpenImage';
            app.RightPanel = uipanel(app.GridLayout);
            app.ImageAxes = uiaxes(app.RightPanel);
            title(app.ImageAxes, 'Original Image')
            app.EdgeAxes = uiaxes(app.RightPanel);
            title(app.EdgeAxes, 'Processed Images')
            app.EdgeDetectionToolLabel = uilabel(app.RightPanel);
            app.EdgeDetectionToolLabel.Text = 'Edge Detection Tool';
            % Show the figure after all components are created
            app.EdgeDetectionAnalysisAppUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = EdgeAnalysisTool

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.EdgeDetectionAnalysisAppUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.EdgeDetectionAnalysisAppUIFigure)
        end
    end
end